classdef ANAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RegressionTab                   matlab.ui.container.Tab
        ANNhyperparanetersoptimizationPanel  matlab.ui.container.Panel
        HiddenlayersPanel               matlab.ui.container.Panel
        LampLa_5                        matlab.ui.control.Lamp
        LampLa_4                        matlab.ui.control.Lamp
        LampLa_3                        matlab.ui.control.Lamp
        LampLa_2                        matlab.ui.control.Lamp
        LampLa_1                        matlab.ui.control.Lamp
        Layer5Switch                    matlab.ui.control.Switch
        Layer4Switch                    matlab.ui.control.Switch
        Layer3Switch                    matlab.ui.control.Switch
        Layer2Switch                    matlab.ui.control.Switch
        Layer1Switch                    matlab.ui.control.Switch
        Layer4Spinner                   matlab.ui.control.Spinner
        Layer4SpinnerLabel              matlab.ui.control.Label
        Layer3Spinner                   matlab.ui.control.Spinner
        Layer3SpinnerLabel              matlab.ui.control.Label
        Layer2Spinner                   matlab.ui.control.Spinner
        Layer2Label                     matlab.ui.control.Label
        Layer1Spinner                   matlab.ui.control.Spinner
        Layer1SpinnerLabel              matlab.ui.control.Label
        Layer5Spinner                   matlab.ui.control.Spinner
        Layer5SpinnerLabel              matlab.ui.control.Label
        LayerssizesSpinner              matlab.ui.control.Spinner
        LayerssizesSpinnerLabel         matlab.ui.control.Label
        StarttheoptimizationprocessButton  matlab.ui.control.Button
        OptimizedhyperparametersPanel   matlab.ui.container.Panel
        toSpinner                       matlab.ui.control.Spinner
        toSpinnerLabel                  matlab.ui.control.Label
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        NumberofhiddenlayersCheckBox    matlab.ui.control.CheckBox
        LayersrangeSpinner              matlab.ui.control.Spinner
        LayersrangeSpinnerLabel         matlab.ui.control.Label
        StandrizationCheckBox           matlab.ui.control.CheckBox
        RegularizationLambdaCheckBox    matlab.ui.control.CheckBox
        LayerbiasesCheckBox             matlab.ui.control.CheckBox
        LayersweightsCheckBox           matlab.ui.control.CheckBox
        ActivationfunctionCheckBox      matlab.ui.control.CheckBox
        NumberofevaluationsSpinner      matlab.ui.control.Spinner
        NumberofevaluationsSpinnerLabel  matlab.ui.control.Label
        ResultsoftheoptimizationPanel   matlab.ui.container.Panel
        SaveregressionresultsButton     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        StatusofoutputmeasureoptimizationGauge  matlab.ui.control.LinearGauge
        StatusofoutputmeasureoptimizationGaugeLabel  matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        LoadingDatasetsPanel            matlab.ui.container.Panel
        NoteTextArea                    matlab.ui.control.TextArea
        NoteTextAreaLabel               matlab.ui.control.Label
        LoadTestingdataButton           matlab.ui.control.Button
        LoadTrainingdataButton          matlab.ui.control.Button
        FilepathTextArea_2              matlab.ui.control.TextArea
        FilepathTextArea_2Label         matlab.ui.control.Label
        FilepathTextArea                matlab.ui.control.TextArea
        FilepathTextAreaLabel           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        NumberofcolumnsEditField_2      matlab.ui.control.NumericEditField
        NumberofcolumnsEditFieldLabel   matlab.ui.control.Label
        DatasetsizeEditField_2          matlab.ui.control.NumericEditField
        DatasetsizeEditField_2Label     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        NumberofcolumnsEditField_1      matlab.ui.control.NumericEditField
        NumberofcolumnsEditField_2Label  matlab.ui.control.Label
        DatasetsizeEditField_1          matlab.ui.control.NumericEditField
        DatasetsizeEditFieldLabel       matlab.ui.control.Label
        MultiobjectiveoptimizationTab   matlab.ui.container.Tab
        OptimizationparametersPanel     matlab.ui.container.Panel
        SPEA2Panel                      matlab.ui.container.Panel
        TextArea_13                     matlab.ui.control.TextArea
        PopulationSizeSpinner_4         matlab.ui.control.Spinner
        PopulationSizeSpinner_3Label_2  matlab.ui.control.Label
        TextArea_12                     matlab.ui.control.TextArea
        MaxIterationsSpinner_4          matlab.ui.control.Spinner
        MaxIterationsSpinner_4Label     matlab.ui.control.Label
        TextArea_11                     matlab.ui.control.TextArea
        PopulationSizeSpinner_3         matlab.ui.control.Spinner
        PopulationSizeSpinner_3Label    matlab.ui.control.Label
        ParetosearchPanel               matlab.ui.container.Panel
        TextArea_4                      matlab.ui.control.TextArea
        MaxIterationsSpinner            matlab.ui.control.Spinner
        MaxIterationsSpinnerLabel       matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        ParetoSetSizeSpinner            matlab.ui.control.Spinner
        ParetoSetSizeSpinnerLabel       matlab.ui.control.Label
        NSGAIIPanel                     matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        PopulationSizeSpinner           matlab.ui.control.Spinner
        PopulationSizeLabel             matlab.ui.control.Label
        MaxGenerationsSpinner           matlab.ui.control.Spinner
        MaxGenerationsLabel             matlab.ui.control.Label
        OptimizationResultsPanel        matlab.ui.container.Panel
        DecesionmatrixDesignvariablesPanel  matlab.ui.container.Panel
        UITable5                        matlab.ui.control.Table
        DecesionmatrixObjectivesPanel   matlab.ui.container.Panel
        UITable4                        matlab.ui.control.Table
        InputdatapreparationPanel       matlab.ui.container.Panel
        NumberoftrainedmodelsEditField  matlab.ui.control.NumericEditField
        NumberoftrainedmodelsEditFieldLabel  matlab.ui.control.Label
        FilepathTextArea_3              matlab.ui.control.TextArea
        FilepathTextArea_3Label         matlab.ui.control.Label
        LoadregressionmodelsIfyouhaveButton  matlab.ui.control.Button
        ExtractinformationButton        matlab.ui.control.Button
        DesignvariablesPanel            matlab.ui.container.Panel
        UITable3                        matlab.ui.control.Table
        ObjectivesPanel                 matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        MultiObjectiveOptimizationalgorithmButtonGroup  matlab.ui.container.ButtonGroup
        SaveDecesionmatrixButton        matlab.ui.control.Button
        OptimizeandgettheresultsButton  matlab.ui.control.Button
        Lamp_12                         matlab.ui.control.Lamp
        SPEA2Button                     matlab.ui.control.ToggleButton
        Lamp_8                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        ParetosearchButton              matlab.ui.control.ToggleButton
        NSGAIIButton                    matlab.ui.control.ToggleButton
        WeightingofperformancemeasuresTab  matlab.ui.container.Tab
        GettingPanel                    matlab.ui.container.Panel
        SaveANPweightsButton            matlab.ui.control.Button
        CalculateANPweightsButton       matlab.ui.control.Button
        ResultsPanel_2                  matlab.ui.container.Panel
        UITable8                        matlab.ui.control.Table
        PairwisecomparisonsforinterdependanceamongcriteriaPanel  matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        Expert1Tab_2                    matlab.ui.container.Tab
        WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel  matlab.ui.container.Panel
        SaveAHPweightsButton            matlab.ui.control.Button
        Note2TextArea                   matlab.ui.control.TextArea
        Note2TextAreaLabel              matlab.ui.control.Label
        CalculateAHPweightsButton       matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        ConsistencyratioCRPanel         matlab.ui.container.Panel
        UITable7                        matlab.ui.control.Table
        MeasuresweightsPanel            matlab.ui.container.Panel
        UITable6                        matlab.ui.control.Table
        Note1TextArea                   matlab.ui.control.TextArea
        Note1TextAreaLabel              matlab.ui.control.Label
        ExpertspairwisecomparisonmatricesPanel  matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        Expert1Tab                      matlab.ui.container.Tab
        GeneratepairwisecomparisonmatrixButton  matlab.ui.control.Button
        NumberofexpertsSpinner          matlab.ui.control.Spinner
        NumberofexpertsSpinnerLabel     matlab.ui.control.Label
        SelectionTOPSISTab              matlab.ui.container.Tab
        DeceionmakingSingleobjectiveTOPSISUsingANPPanel  matlab.ui.container.Panel
        SaveANPTOPSISdataButton         matlab.ui.control.Button
        BestdesignperformancemeasuresPanel_2  matlab.ui.container.Panel
        UITable11_2                     matlab.ui.control.Table
        BestdesignvariablesPanel_2      matlab.ui.container.Panel
        UITable10_2                     matlab.ui.control.Table
        RankingthedesignsPanel_2        matlab.ui.container.Panel
        UITable9_2                      matlab.ui.control.Table
        RankdesignswithTOPSISandselectthebestdesignButton_2  matlab.ui.control.Button
        DeceionmakingMultiobjectiveTOPSISUsingAHPPanel  matlab.ui.container.Panel
        SaveAHPTOPSISdataButton         matlab.ui.control.Button
        BestdesignperformancemeasuresPanel  matlab.ui.container.Panel
        UITable11                       matlab.ui.control.Table
        BestdesignvariablesPanel        matlab.ui.container.Panel
        UITable10                       matlab.ui.control.Table
        RankingthedesignsPanel          matlab.ui.container.Panel
        UITable9                        matlab.ui.control.Table
        RankdesignswithTOPSISandselectthebestdesignButton  matlab.ui.control.Button
        SelectionSingleobjectiveoptimizationTab  matlab.ui.container.Tab
        OptimizeandgettheresultsButton_2  matlab.ui.control.Button
        ParticleSwarmPanel              matlab.ui.container.Panel
        SaveParticleswarmresultsButton  matlab.ui.control.Button
        BestdesignperformancemeasuresPanel_5  matlab.ui.container.Panel
        UITable11_5                     matlab.ui.control.Table
        BestdesignvariablesPanel_5      matlab.ui.container.Panel
        UITable10_5                     matlab.ui.control.Table
        PatternSearchPanel              matlab.ui.container.Panel
        SavePatternsearchresultsButton  matlab.ui.control.Button
        BestdesignperformancemeasuresPanel_4  matlab.ui.container.Panel
        UITable11_4                     matlab.ui.control.Table
        BestdesignvariablesPanel_4      matlab.ui.container.Panel
        UITable10_4                     matlab.ui.control.Table
        GAPanel_2                       matlab.ui.container.Panel
        SaveGAresultsButton             matlab.ui.control.Button
        BestdesignperformancemeasuresPanel_3  matlab.ui.container.Panel
        UITable11_3                     matlab.ui.control.Table
        BestdesignvariablesPanel_3      matlab.ui.container.Panel
        UITable10_3                     matlab.ui.control.Table
        OptimizationparametersPanel_2   matlab.ui.container.Panel
        ParicleawarmPanel               matlab.ui.container.Panel
        TextArea_10                     matlab.ui.control.TextArea
        TextArea_9                      matlab.ui.control.TextArea
        SwarmSizeSpinner                matlab.ui.control.Spinner
        SwarmSizeSpinnerLabel           matlab.ui.control.Label
        MaxIterationsSpinner_3          matlab.ui.control.Spinner
        MaxIterationsSpinner_3Label     matlab.ui.control.Label
        PatternsearchPanel              matlab.ui.container.Panel
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        TextArea_8                      matlab.ui.control.TextArea
        MaxIterationsSpinner_2          matlab.ui.control.Spinner
        MaxIterationsSpinner_2Label     matlab.ui.control.Label
        GAPanel                         matlab.ui.container.Panel
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        PopulationSizeSpinner_2         matlab.ui.control.Spinner
        PopulationSizeSpinner_2Label    matlab.ui.control.Label
        MaxGenerationsSpinner_2         matlab.ui.control.Spinner
        MaxGenerationsSpinner_2Label    matlab.ui.control.Label
        SingleobjectiveoptimizationButtonGroup  matlab.ui.container.ButtonGroup
        WeightingalgorithmSwitch        matlab.ui.control.Switch
        WeightingalgorithmSwitchLabel   matlab.ui.control.Label
        Lamp_11                         matlab.ui.control.Lamp
        Lamp_10                         matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        ParticleSwarmButton             matlab.ui.control.ToggleButton
        PatternSearchButton             matlab.ui.control.ToggleButton
        GAButton                        matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
        DataSet_train % Obtained training data set.
        DataSet_test % Obtained testing data set.
        Output_names
        input_names
        input_Training_Data
        NNmodel
        expcomp
        expcomp2
        criteria_weights_exp
        Decesion_matrix
        Decesion_matrix_variables
        LowerBound 
        UpperBound 
        IntegerPosition
        OptNvars
        table_data
        Table_TOPSIS_AHP
        Table_TOPSIS_ANP

    end
    
    methods (Access = private)
        
        function [CR, weig] = AHP(app,PWC_Matrix)
                G_M=geomean(PWC_Matrix,2);
                weig=zeros(1,size(PWC_Matrix,1));
                for i=1:size(PWC_Matrix,1)
                    weig(i)=G_M(i)/sum(G_M);
                end
                lambda_max=sum((PWC_Matrix*weig')./(size(PWC_Matrix,1)*weig'));
                CI=(lambda_max-size(PWC_Matrix,1))/(size(PWC_Matrix,1)-1);
                RC=1.98*(size(PWC_Matrix,1)-2)/size(PWC_Matrix,1);
                CR=CI/RC;      % less than 0.1
        end

        function Table = TOPSIS(app,Decesion_matrix, criteria_weights, Criteria, cost_criteria_index)
                for i=1:length(cost_criteria_index)
                    idx=strcmp(Criteria,cost_criteria_index(i)); 
                    Decesion_matrix(:,idx)=1./Decesion_matrix(:,idx);
                end
                    Decesion_matrix_normalized= Decesion_matrix./[sqrt(sum(Decesion_matrix.^2))];
                    Decesion_matrix_normalized_weighted=Decesion_matrix_normalized.*criteria_weights;
                    best_alternative=max(Decesion_matrix_normalized_weighted);
                    worst_alternative=min(Decesion_matrix_normalized_weighted);
                    %%% distance from the best and worst solution %%%%
                    [dis_best,dis_worst] = DIS(app,Decesion_matrix_normalized_weighted,best_alternative,worst_alternative);
                    Relative_closeness=dis_worst./(dis_best+dis_worst);
                    Rank=tiedrank(1./tiedrank(Relative_closeness));
                    %%% create table with the final result %%%%
                    Final_matrix=[dis_worst,dis_best,Relative_closeness,Rank];
                    Table = array2table(Final_matrix,"VariableNames",{'d-','d+','RC','Rank'});
        end

        function [d_b,d_w] = DIS(app,A,b,w)
                    % A: decesion matrix, b: best solution, w: worest solution
                    d_b = sqrt(sum((A-b).^2,2));
                    d_w = sqrt(sum((A-w).^2,2));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTrainingdataButton
        function LoadTrainingdataButtonPushed(app, event)
                    filterspec1 = {'*.xlsx';
                                   '*.slx';
                                   '*.csv'};  % specify the extension of the file
                        
                    try
                        [file1,location1] = uigetfile(filterspec1); % open browse page
                        app.FilepathTextArea.Value= "User selected: "+fullfile(location1,file1);
                        selectedExcelfile1 = fullfile(location1,file1);  % write the full path of the file with file name
                        selectedExcelfile12 = fullfile(location1,[]);  % write the full path of the file without file name
                        addpath(selectedExcelfile12); % Add the path 
                        app.DataSet_train = readtable(selectedExcelfile1);

                        app.NumberofcolumnsEditField_1.Value = size(app.DataSet_train.Variables,2);
                        app.DatasetsizeEditField_1.Value = size(app.DataSet_train.Variables,1);

                        %SIMname = erase(file1,'.urdf');
                        %save_system(SIMname);
                    catch ME
                        uialert(app.UIFigure,ME.message,"File Error");
                    return;
                    end  
        end

        % Button pushed function: LoadTestingdataButton
        function LoadTestingdataButtonPushed(app, event)
                    filterspec2 = {'*.xlsx';
                                   '*.slx';
                                   '*.csv'};  % specify the extension of the file
                    try
                        [file2,location2] = uigetfile(filterspec2); % open browse page
                        app.FilepathTextArea_2.Value= "User selected: "+fullfile(location2,file2);
                        selectedExcelfile2 = fullfile(location2,file2);  % write the full path of the file with file name
                        selectedExcelfile22 = fullfile(location2,[]);  % write the full path of the file without file name
                        addpath(selectedExcelfile22); % Add the path 
                        app.DataSet_test = readtable(selectedExcelfile2);

                        app.NumberofcolumnsEditField_2.Value = size(app.DataSet_test.Variables,2);
                        app.DatasetsizeEditField_2.Value = size(app.DataSet_test.Variables,1);

                        %SIMname = erase(file1,'.urdf');
                        %save_system(SIMname);
                    catch ME
                        uialert(app.UIFigure,ME.message,"File Error");
                    return;
                    end 
        end

        % Button pushed function: StarttheoptimizationprocessButton
        function StarttheoptimizationprocessButtonPushed(app, event)

            
            try

            all_names = string(app.DataSet_train.Properties.VariableNames);
            app.input_names = all_names(startsWith(all_names,"in_"));
            app.Output_names = all_names(startsWith(all_names,"out_"));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.StatusofoutputmeasureoptimizationGauge.Limits=[1,length(app.Output_names)];
            app.StatusofoutputmeasureoptimizationGauge.MajorTicks = 1:1:length(app.Output_names);
            app.StatusofoutputmeasureoptimizationGauge.MajorTickLabels = erase(app.Output_names,"out_");
            rng("default")
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % prepare the data for each variable

            Training_Data = app.DataSet_train.Variables;
            test_Data = app.DataSet_test.Variables;

            app.input_Training_Data = Training_Data(:,startsWith(all_names,"in_"));
            output_Training_Data = Training_Data(:,startsWith(all_names,"out_"));

            input_testing_Data = test_Data(:,startsWith(all_names,"in_"));
            output_testing_Data = test_Data(:,startsWith(all_names,"out_"));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Starting hyper parameters optimization %%%

            str = "---------------------------------------------------------------------------------------";

            %axes = gcf;
            %app.UIFigure.HandleVisibility = 'on';
            %plot(app.UIAxes,1,1,'-b'); 
            %hold(app.UIAxes);            

            for i=1:size(output_Training_Data,2)

                 app.StatusofoutputmeasureoptimizationGauge.Value=i;

                 str = str + newline + string(i) + ": Starting hyper parameter optimization of "+app.Output_names(i)+" ANN .............., Please wait.";

                 app.StatusTextArea.Value = str ;
                 
                 params = hyperparameters("fitrnet",app.input_Training_Data,output_Training_Data(:,i));
                 % Hyperparameters tuning range
                 %%%%%%%%%%%%%%%%%%%%%%%%%%%
                 
                 if app.NumberofhiddenlayersCheckBox.Value == 1 
                        params(1).Range = [app.LayersrangeSpinner.Value app.toSpinner.Value];  
                        params(1).Optimize=true;
                 else 
                        params(1).Optimize=false;
                 end 
                 %%%%%%%%%%%%%%%%%%%%%%%%%%%
                 
                 if app.ActivationfunctionCheckBox.Value == 1
                        params(2).Range={'relu' 'sigmoid' 'tanh'};
                        params(2).Optimize=true;
                 else
                        params(2).Optimize=false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%%%%
                 if app.StandrizationCheckBox.Value == 1
                        params(3).Optimize=true;
                 else
                        params(3).Optimize=false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%%%
                 if app.RegularizationLambdaCheckBox.Value == 1
                        params(4).Optimize=true;
                 else
                        params(4).Optimize=false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%%
                 if app.LayersweightsCheckBox.Value == 1
                        params(5).Optimize=true;
                 else
                        params(5).Optimize=false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%%
                 if app.LayerbiasesCheckBox.Value == 1
                        params(6).Optimize=true;
                 else
                        params(6).Optimize=false;
                 end 
                 %%%%%%%%%%%%%%%%%%%%%%%%%
                 if app.Layer1Switch.Value == "On"
                        params(7).Optimize=true;
                        params(7).Range = [1 500];
                 else
                        params(7).Optimize=false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%%
                 
                 if app.Layer2Switch.Value == "On"
                        params(8).Optimize=true;
                        params(8).Range=[1 500];
                 else
                        params(8).Optimize=false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%%
                 
                 if app.Layer3Switch.Value == "On"
                        params(9).Optimize = true;
                        params(9).Range = [1 500];
                 else
                        params(9).Optimize = false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%
                 
                 if app.Layer4Switch.Value == "On"
                        params(10).Optimize = true;
                        params(10).Range = [1 500];
                 else
                        params(10).Optimize = false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%%
                 
                 if app.Layer5Switch.Value == "On"
                        params(11).Optimize = true;
                        params(11).Range = [1 500];
                 else
                        params(11).Optimize = false;
                 end
                 %%%%%%%%%%%%%%%%%%%%%%%
                 %cla(app.UIAxes);

                 if app.LayerssizesSpinner.Value == 2
                     LayerSizes = [app.Layer1Spinner.Value app.Layer2Spinner.Value];
                 elseif app.LayerssizesSpinner.Value == 3
                     LayerSizes = [app.Layer1Spinner.Value app.Layer2Spinner.Value app.Layer3Spinner.Value];
                 elseif app.LayerssizesSpinner.Value == 4
                     LayerSizes = [app.Layer1Spinner.Value app.Layer2Spinner.Value app.Layer3Spinner.Value app.Layer4Spinner.Value];
                 elseif app.LayerssizesSpinner.Value == 5
                     LayerSizes = [app.Layer1Spinner.Value app.Layer2Spinner.Value app.Layer3Spinner.Value app.Layer4Spinner.Value app.Layer5Spinner.Value];
                 elseif app.LayerssizesSpinner.Value == 1
                     LayerSizes = [app.Layer1Spinner.Value];
                 end

                 
                 app.NNmodel{i} = fitrnet(app.input_Training_Data,output_Training_Data(:,i),"LayerSizes",LayerSizes,"OptimizeHyperparameters",params,"HyperparameterOptimizationOptions",...
                              struct("MaxObjectiveEvaluations", app.NumberofevaluationsSpinner.Value ,"Optimizer","gridsearch","NumGridDivisions",10,"AcquisitionFunctionName","expected-improvement-plus","KFold",10,"Repartition",true));

                 TrainingMSE(i) = loss(app.NNmodel{i},app.input_Training_Data,output_Training_Data(:,i));
                 testMSE(i) = loss(app.NNmodel{i},input_testing_Data,output_testing_Data(:,i));

                 str = str + newline + "The optimization of the current performance measure ANN is finished.";
                 str = str + newline + "---------------------------------------------------------------------------------------";

                 app.StatusTextArea.Value = str ; 


            end

            for i=1:size(output_Training_Data,2)

                   app.table_data{i,1} = strjoin(string(app.NNmodel{i}.ModelParameters.LayerSizes));
                   app.table_data{i,2} = string(app.NNmodel{i}.ModelParameters.Activations);
                   app.table_data{i,3} = string(app.NNmodel{i}.ModelParameters.LayerWeightsInitializer);
                   app.table_data{i,4} = string(app.NNmodel{i}.ModelParameters.LayerBiasesInitializer);
                   app.table_data{i,5} = string(app.NNmodel{i}.ModelParameters.Lambda);

                if app.NNmodel{i}.ModelParameters.StandardizeData ==1 
                   app.table_data{i,6} = "True";
                else
                   app.table_data{i,6} = "False";
                end

                   app.table_data{i,7} = TrainingMSE(i);
                   app.table_data{i,8} = testMSE(i);
    
           end

               var_names=["Layer sizes","Activation function","Layer weights initializer","Layer biases initializer","lambda","Standrization","Training RMS","Testing RMSE"];

               app.UITable.Data = cell2table(app.table_data);
               app.UITable.ColumnName =  var_names;
               app.UITable.RowName = erase(app.Output_names,"out_");

           catch ME
               uialert(app.UIFigure,ME.message,"File Error");
           return;
           end 

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        % Value changed function: LayerbiasesCheckBox
        function LayerbiasesCheckBoxValueChanged(app, event)

            if app.LayerbiasesCheckBox.Value == 1
                app.Lamp.Color = [0,1,0];
            else
                app.Lamp.Color = [1,0,0];
            end

        end

        % Value changed function: NumberofhiddenlayersCheckBox
        function NumberofhiddenlayersCheckBoxValueChanged(app, event)

            if app.NumberofhiddenlayersCheckBox.Value == 1
                app.Lamp_2.Color = [0,1,0];
                app.LayersrangeSpinner.Visible = "on";
                app.LayersrangeSpinnerLabel.Visible = "on";

                app.toSpinner.Visible = "on";
                app.toSpinnerLabel.Visible = "on";


            else
                app.Lamp_2.Color = [1,0,0];
                app.LayersrangeSpinner.Visible = "off";
                app.LayersrangeSpinnerLabel.Visible = "off";

                app.toSpinner.Visible = "off";
                app.toSpinnerLabel.Visible = "off";

            end


        end

        % Value changed function: LayersweightsCheckBox
        function LayersweightsCheckBoxValueChanged(app, event)

            if app.LayersweightsCheckBox.Value == 1
                app.Lamp_3.Color = [0,1,0];
            else
                app.Lamp_3.Color = [1,0,0];
            end

        end

        % Value changed function: ActivationfunctionCheckBox
        function ActivationfunctionCheckBoxValueChanged(app, event)

            if app.ActivationfunctionCheckBox.Value == 1
                app.Lamp_4.Color = [0,1,0];
            else
                app.Lamp_4.Color = [1,0,0];
            end

        end

        % Value changed function: StandrizationCheckBox
        function StandrizationCheckBoxValueChanged(app, event)

            if app.StandrizationCheckBox.Value == 1
                app.Lamp_5.Color = [0,1,0];
            else
                app.Lamp_5.Color = [1,0,0];
            end
            
        end

        % Value changed function: RegularizationLambdaCheckBox
        function RegularizationLambdaCheckBoxValueChanged(app, event)
            
            if app.RegularizationLambdaCheckBox.Value == 1
                app.Lamp_6.Color = [0,1,0];
            else
                app.Lamp_6.Color = [1,0,0];
            end

        end

        % Value changed function: Layer1Switch
        function Layer1SwitchValueChanged(app, event)

            if app.Layer1Switch.Value == "On"
                app.LampLa_1.Color = [0,1,0];
            else
                app.LampLa_1.Color = [1,0,0];
            end
            
        end

        % Value changed function: Layer2Switch
        function Layer2SwitchValueChanged(app, event)

            if app.Layer2Switch.Value == "On"
                app.LampLa_2.Color = [0,1,0];
            else
                app.LampLa_2.Color = [1,0,0];
            end
            
        end

        % Value changed function: Layer3Switch
        function Layer3SwitchValueChanged(app, event)

            if app.Layer3Switch.Value == "On"
                app.LampLa_3.Color = [0,1,0];
            else
                app.LampLa_3.Color = [1,0,0];
            end
            
        end

        % Value changed function: Layer4Switch
        function Layer4SwitchValueChanged(app, event)

            if app.Layer4Switch.Value == "On"
                app.LampLa_4.Color = [0,1,0];
            else
                app.LampLa_4.Color = [1,0,0];
            end
            
        end

        % Value changed function: Layer5Switch
        function Layer5SwitchValueChanged(app, event)

            if app.Layer5Switch.Value == "On"
                app.LampLa_5.Color = [0,1,0];
            else
                app.LampLa_5.Color = [1,0,0];
            end
            
        end

        % Selection changed function: 
        % MultiObjectiveOptimizationalgorithmButtonGroup
        function MultiObjectiveOptimizationalgorithmButtonGroupSelectionChanged(app, event)
            
            if app.NSGAIIButton.Value == 1

                app.Lamp_7.Color = [0,1,0];
                app.Lamp_8.Color = [1,0,0];
                app.Lamp_12.Color = [1,0,0];

            elseif app.ParetosearchButton.Value == 1 

                app.Lamp_7.Color = [1,0,0];
                app.Lamp_8.Color = [0,1,0];
                app.Lamp_12.Color = [1,0,0];

            elseif app.SPEA2Button.Value == 1

                app.Lamp_7.Color = [1,0,0];
                app.Lamp_8.Color = [1,0,0];
                app.Lamp_12.Color = [0,1,0];

            end

        end

        % Button down function: 
        % MultiObjectiveOptimizationalgorithmButtonGroup
        function MultiObjectiveOptimizationalgorithmButtonGroupButtonDown(app, event)


        end

        % Button pushed function: ExtractinformationButton
        function ExtractinformationButtonPushed(app, event)

               objectives_table_names = ["Maximize"];
               app.UITable2.ColumnName =  objectives_table_names;
               app.UITable2.RowName = erase(app.Output_names,"out_");
               app.UITable2.ColumnEditable = [true];
               app.UITable2.ColumnFormat = {'logical'};

               Table2Data = [false(length(app.Output_names),1)];

               app.UITable2.Data = Table2Data;

               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

               variables_table_names=["Lower bound","Upper bound","Integer"];
               app.UITable3.ColumnName =  variables_table_names;
               app.UITable3.RowName = erase(app.input_names,"in_");
               app.UITable3.ColumnEditable = [true true true];
               app.UITable3.ColumnFormat = {'numeric', 'numeric' ,'logical'};

               Table3Data = [num2cell(min(app.input_Training_Data)'),num2cell(max(app.input_Training_Data)'),num2cell(false(length(app.input_names),1))];

               app.UITable3.Data = Table3Data;

               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

               app.MaxGenerationsSpinner.Value = 200 * length(app.input_names);
               app.MaxGenerationsSpinner_2.Value = 100 * length(app.input_names);

               if length(app.input_names) <= 5
                   app.PopulationSizeSpinner.Value = 50;
                   app.PopulationSizeSpinner_2.Value = 50;
               else
                   app.PopulationSizeSpinner.Value = 200;
                   app.PopulationSizeSpinner_2.Value = 200;
               end

               app.MaxIterationsSpinner.Value = 100 * (length(app.input_names) + length(app.Output_names));
               app.MaxIterationsSpinner_2.Value = 100 * length(app.input_names);
               app.MaxIterationsSpinner_3.Value = 200 * length(app.input_names);
               app.MaxIterationsSpinner_4.Value = 100 * (length(app.input_names) + length(app.Output_names));
               
               app.ParetoSetSizeSpinner.Value = max(length(app.Output_names),60);
               app.AlgorithmDropDown.Value = 'classic';
               app.SwarmSizeSpinner.Value = min(100, 10 * length(app.input_names));
               app.PopulationSizeSpinner_3.Value = max(length(app.Output_names),60);

               app.PopulationSizeSpinner_4.Value = max(length(app.Output_names),40);

               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end

        % Button pushed function: OptimizeandgettheresultsButton
        function OptimizeandgettheresultsButtonPushed(app, event)

            addstr="";
            maximization = app.UITable2.Data(:,1);
                for i=1:length(app.NNmodel)

                    if maximization(i) == 1
                    fun="-1*predict(Q{"+string(i)+"},x),";  
                    else
                     fun="predict(Q{"+string(i)+"},x)," ;
                    end
                        functions=addstr+fun;
                        addstr=functions;
                end

                    functions="@(x,Q)["+functions+"]";
                    functions = eraseBetween(functions,"{"+string(i)+"},x)","]");
                    newobjectiveFcn=str2func(functions);
                    objectiveFcn=@(x)newobjectiveFcn(x,app.NNmodel);

                    app.LowerBound = cell2mat(app.UITable3.Data(:,1)');
                    app.UpperBound = cell2mat(app.UITable3.Data(:,2)');
                    app.IntegerPosition = find(cell2mat(app.UITable3.Data(:,3))==1)';
                    app.OptNvars = length(app.input_names);

                if app.NSGAIIButton.Value == 1
                    try
                        rng default 
                        options = optimoptions('gamultiobj','PlotFcn',@gaplotpareto,"PopulationSize",app.PopulationSizeSpinner.Value,"MaxGenerations",app.MaxGenerationsSpinner.Value);

                        [x,fval,~,~,~,~] = gamultiobj(objectiveFcn,app.OptNvars,[],[],[],[],app.LowerBound,app.UpperBound,[],app.IntegerPosition,options);

                    catch ME
                        uialert(app.UIFigure,ME.message,"Error");
                    return;
                    end  
                end 

                if app.ParetosearchButton.Value == 1 
                    try
                        rng default 
                        options = optimoptions('paretosearch','PlotFcn','psplotparetof','MaxIterations',app.MaxIterationsSpinner.Value,'ParetoSetSize',app.ParetoSetSizeSpinner.Value);

                        [x,fval,~,~] = paretosearch(objectiveFcn,app.OptNvars,[],[],[],[],app.LowerBound,app.UpperBound,[],options);

                    catch ME
                        uialert(app.UIFigure,ME.message,"Error");
                    return;
                    end  
                end

                if app.SPEA2Button.Value == 1

                        addstr3="";
                    for i=1:length(app.NNmodel)

                    if maximization(i) == 1
                        fun3="-1*predict(Q{"+string(i)+"},x);";  
                    else
                        fun3="predict(Q{"+string(i)+"},x);" ;
                    end
                        functions3=addstr3+fun3;
                        addstr3=functions3;
                    end

                        functions3="@(x,Q)["+functions3+"]";
                        functions3 = eraseBetween(functions3,"{"+string(i)+"},x)","]");
                        newobjectiveFcn3=str2func(functions3);
                        objectiveFcn3=@(x)newobjectiveFcn3(x,app.NNmodel);

                     try
                        rng default 
                        SPEA_options.MaximumIterations=app.MaxIterationsSpinner_4.Value;
                        SPEA_options.PopulationSize=app.PopulationSizeSpinner_3.Value;
                        SPEA_options.ArchieveSize=app.PopulationSizeSpinner_4.Value;
                        SPEA_options.Crossover=0.7;
                        SPEA_options.crossoverParams_gamma=0.1;
                        SPEA_options.mutationParams_h=0.2;

                        [x,fval]=SPEA2(objectiveFcn3,app.OptNvars,app.LowerBound',app.UpperBound',SPEA_options);

                    catch ME
                        uialert(app.UIFigure,ME.message,"Error");
                    return;
                    end  

                end

                app.Decesion_matrix=fval;
                app.Decesion_matrix_variables=x;

                %%%%%%%%%%%%%%%% show the outputs %%%%%%%%%%%%%%%%
               for j=1:size(fval,1)
                   Tab4names(j) = "Design ("+string(j)+")";
               end

               %%%%% convert data of the maximization functions %%%%%%
               app.Decesion_matrix(:,maximization') = -1 * app.Decesion_matrix(:,maximization');
               %%%%%%%%%%%%%%%%%
               app.UITable4.ColumnName =  erase(app.Output_names,"out_");
               app.UITable4.RowName = Tab4names;
               app.UITable4.Data = app.Decesion_matrix;

               app.UITable5.ColumnName =  erase(app.input_names,"in_");
               app.UITable5.RowName = Tab4names;
               app.UITable5.Data = app.Decesion_matrix_variables;
               
        end

        % Button pushed function: GeneratepairwisecomparisonmatrixButton
        function GeneratepairwisecomparisonmatrixButtonPushed(app, event)
                extab = cell(1,app.NumberofexpertsSpinner.Value);
                extab{1} = app.Expert1Tab;
                %%%%%%%%%%%%
                nam= erase(app.Output_names,"out_");
                tabdata{1} = table();
                for i=1:length(app.Output_names)
                    pos = ones(length(app.Output_names),1);
                    pos(i) =2;
                    sz = categorical(pos,1:18,{'0','1','2','3','4','5','6','7','8','9','1/2','1/3','1/4','1/5','1/6','1/7','1/8','1/9'});
                    tabdata{1}.(nam(i)) = sz;
                end
                %%%%%%%%%%%%
                app.expcomp{1} = uitable(extab{1},"Data",tabdata{1},"Position",[0,-8,745,320]);
                %app.expcomp{1}.ColumnName = erase(app.Output_names,"out_");
                app.expcomp{1}.RowName = erase(app.Output_names,"out_");
                app.expcomp{1}.ColumnEditable = true(1,length(app.Output_names));

            for j=2:app.NumberofexpertsSpinner.Value
                extab{j}=uitab(app.TabGroup2,"Title","Expert ("+j+")");
                dt = diag(ones(1,length(app.Output_names)));
                %%%%%%%%%%%%%%
                tabdata{j} = table();
                for i=1:length(app.Output_names)
                    pos = ones(length(app.Output_names),1);
                    pos(i) =2;
                    sz = categorical(pos,1:18,{'0','1','2','3','4','5','6','7','8','9','1/2','1/3','1/4','1/5','1/6','1/7','1/8','1/9'});
                    tabdata{j}.(nam(i)) = sz;
                end
                %%%%%%%%%%%%%%
                app.expcomp{j} = uitable(extab{j},"Data",tabdata{j},"Position",[0,-8,745,320]);
                %app.expcomp{j}.ColumnName = erase(app.Output_names,"out_");
                app.expcomp{j}.RowName = erase(app.Output_names,"out_");
                app.expcomp{j}.ColumnEditable = true(1,length(app.Output_names));
            end

            %%%%%%%%%%%%%%%%%%%%%%%%% Inter dependance matrix %%%%%%%%%%%%%%
                extab2 = cell(1,app.NumberofexpertsSpinner.Value);
                extab2{1} = app.Expert1Tab_2;
                dt = diag(ones(1,length(app.Output_names)));
                app.expcomp2{1} = uitable(extab2{1},"Data",dt,"Position",[3,9,739,289]);
                app.expcomp2{1}.ColumnName = erase(app.Output_names,"out_");
                app.expcomp2{1}.RowName = erase(app.Output_names,"out_");
                app.expcomp2{1}.ColumnEditable = true(1,length(app.Output_names));
                for j=2:app.NumberofexpertsSpinner.Value
                    extab2{j}=uitab(app.TabGroup3,"Title","Expert ("+j+")");
                    dt = diag(ones(1,length(app.Output_names)));
                    app.expcomp2{j} = uitable(extab2{j},"Data",dt,"Position",[3,9,739,289]);
                    app.expcomp2{j}.ColumnName = erase(app.Output_names,"out_");
                    app.expcomp2{j}.RowName = erase(app.Output_names,"out_");
                    app.expcomp2{j}.ColumnEditable = true(1,length(app.Output_names));
                end

            
        end

        % Button pushed function: CalculateAHPweightsButton
        function CalculateAHPweightsButtonPushed(app, event)

            %%%%%%%%%%%%%%% AHP generation %%%%%%%%%%%%%%%%%
        sttay= [0,1,2,3,4,5,6,7,8,9,1/2,1/3,1/4,1/5,1/6,1/7,1/8,1/9];
            for i=1:length(app.expcomp)
                    rt=double(app.expcomp{i}.Data.Variables);
                    for j=1:size(rt,1)
                        for k=1:size(rt,2)
                            ghj(j,k) = sttay(rt(j,k));
                        end
                    end
                ahpPM{i} = ghj;
            end

            % Calculating the weights of metrics for each expert

            app.criteria_weights_exp=cell(1,length(app.expcomp));
            for i=1:length(app.expcomp)
                  [CR_exp(i), app.criteria_weights_exp{i}] = AHP(app,ahpPM{i});
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.UITable7.ColumnName = "CR";
            for j=1:length(app.expcomp)
                exp_names(j,1) = "Expert ("+j+")";
            end
            app.UITable7.RowName = exp_names;
            app.UITable7.Data = CR_exp';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      
            % if weighting of experts is not available
                add=0;
            for i=1:length(app.expcomp)
                    AHP_Weight=app.criteria_weights_exp{i}+add;
                    add=AHP_Weight;
            end

            AHP_Weight=AHP_Weight/length(app.expcomp);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.UITable6.ColumnName = "Weight";
            app.UITable6.RowName = erase(app.Output_names,"out_");
            app.UITable6.Data = AHP_Weight';
        end

        % Button pushed function: CalculateANPweightsButton
        function CalculateANPweightsButtonPushed(app, event)
            add=0;
            for i=1:length(app.expcomp2)
                normalized_inner_weight{i} = (app.expcomp2{i}.Data)./(sum(app.expcomp2{i}.Data));
                weighted_inner_weight{i} = normalized_inner_weight{i} * app.criteria_weights_exp{i}';
                ANP_Weight=weighted_inner_weight{i}+add;
                add=ANP_Weight;
            end
            ANP_Weight=ANP_Weight/length(app.expcomp2);
            app.UITable8.ColumnName = "Weight";
            app.UITable8.RowName = erase(app.Output_names,"out_");
            app.UITable8.Data = ANP_Weight;

        end

        % Button pushed function: 
        % RankdesignswithTOPSISandselectthebestdesignButton
        function RankdesignswithTOPSISandselectthebestdesignButtonPushed(app, event)
            for j=1:size(app.Decesion_matrix,1)
                   Tab9names(j,1) = "Design ("+string(j)+")";
            end
            Criteria = erase(app.Output_names,"out_");
            cost_criteria_index = Criteria(app.UITable2.Data(:,1)'==0);

            %%%%%%%%%%%%%%%%%%%  AHP  %%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.Table_TOPSIS_AHP = TOPSIS(app,app.Decesion_matrix, app.UITable6.Data', Criteria, cost_criteria_index);
            app.UITable9.Data = app.Table_TOPSIS_AHP;
            app.UITable9.RowName = Tab9names;
            app.UITable9.ColumnName = ["d-","d+","RC","Rank"];

            best_design_variable = app.Decesion_matrix_variables(app.Table_TOPSIS_AHP.Rank==1,:);
            app.UITable10.Data = best_design_variable;
            app.UITable10.ColumnName = erase(app.input_names,"in_");
            app.UITable10.RowName = Tab9names(app.Table_TOPSIS_AHP.Rank==1);

            best_design_measures = app.Decesion_matrix(app.Table_TOPSIS_AHP.Rank==1,:); 
            app.UITable11.Data = best_design_measures;
            app.UITable11.ColumnName = erase(app.Output_names,"out_");
            app.UITable11.RowName = Tab9names(app.Table_TOPSIS_AHP.Rank==1);


        end

        % Button pushed function: 
        % RankdesignswithTOPSISandselectthebestdesignButton_2
        function RankdesignswithTOPSISandselectthebestdesignButton_2Pushed(app, event)

            for j=1:size(app.Decesion_matrix,1)
                   Tab9names(j,1) = "Design ("+string(j)+")";
            end
            Criteria = erase(app.Output_names,"out_");
            cost_criteria_index = Criteria(app.UITable2.Data(:,1)'==0);

            %%%%%%%%%%%%%%%%%%%  ANP  %%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.Table_TOPSIS_ANP = TOPSIS(app,app.Decesion_matrix, app.UITable8.Data', Criteria, cost_criteria_index);
            app.UITable9_2.Data = app.Table_TOPSIS_ANP;
            app.UITable9_2.RowName = Tab9names;
            app.UITable9_2.ColumnName = ["d-","d+","RC","Rank"];

            best_design_variable = app.Decesion_matrix_variables(app.Table_TOPSIS_ANP.Rank==1,:);
            app.UITable10_2.Data = best_design_variable;
            app.UITable10_2.ColumnName = erase(app.input_names,"in_");
            app.UITable10_2.RowName = Tab9names(app.Table_TOPSIS_ANP.Rank==1);

            best_design_measures = app.Decesion_matrix(app.Table_TOPSIS_ANP.Rank==1,:); 
            app.UITable11_2.Data = best_design_measures;
            app.UITable11_2.ColumnName = erase(app.Output_names,"out_");
            app.UITable11_2.RowName = Tab9names(app.Table_TOPSIS_ANP.Rank==1);
            
        end

        % Selection changed function: 
        % SingleobjectiveoptimizationButtonGroup
        function SingleobjectiveoptimizationButtonGroupSelectionChanged(app, event)

            if app.GAButton.Value == 1

                app.Lamp_9.Color  = [0,1,0];
                app.Lamp_10.Color = [1,0,0];
                app.Lamp_11.Color = [1,0,0];

            elseif app.PatternSearchButton.Value == 1 

                app.Lamp_9.Color  = [1,0,0];
                app.Lamp_10.Color = [0,1,0];
                app.Lamp_11.Color = [1,0,0];

            elseif app.ParticleSwarmButton.Value == 1 

                app.Lamp_9.Color  = [1,0,0];
                app.Lamp_10.Color = [1,0,0];
                app.Lamp_11.Color = [0,1,0];

            end
            
        end

        % Button pushed function: OptimizeandgettheresultsButton_2
        function OptimizeandgettheresultsButton_2Pushed(app, event)
            
            addstr="";
            maximization = app.UITable2.Data(:,1);

            AHP_weight = app.UITable6.Data;
            ANP_weight = app.UITable8.Data;

                for i=1:length(app.NNmodel)

                    if maximization(i) == 1
                        if app.WeightingalgorithmSwitch.Value == "AHP"
                            fun="-1*"+string(AHP_weight(i))+"*predict(Q{"+string(i)+"},x)";  
                        elseif app.WeightingalgorithmSwitch.Value == "ANP"
                            fun="-1*"+string(ANP_weight(i))+"*predict(Q{"+string(i)+"},x)";  
                        end 
                    else
                        if app.WeightingalgorithmSwitch.Value == "AHP"
                            fun= "+"+string(AHP_weight(i))+"*predict(Q{"+string(i)+"},x)"; 
                        elseif app.WeightingalgorithmSwitch.Value == "ANP"
                            fun= "+"+string(ANP_weight(i))+"*predict(Q{"+string(i)+"},x)"; 
                        end 
                    end
                        functions=addstr+fun;
                        addstr=functions;
                end

                    functions="@(x,Q)"+functions;
                    functions = eraseBetween(functions,"{"+string(i)+"},x)","]");
                    newobjectiveFcn=str2func(functions);
                    objectiveFcn=@(x)newobjectiveFcn(x,app.NNmodel);
%%%%%%%%%%%%%%%%%%%%%%%%% get the output values %%%%%%%%%%%%%%%%%%%%%%%%%%%%
                addstr2="";
                for i=1:length(app.NNmodel)
                     fun2="predict(Q{"+string(i)+"},x)," ;
                     functions2=addstr2+fun2;
                     addstr2=functions2;
                end
                    functions2="@(x,Q)["+functions2+"]";
                    functions2 = eraseBetween(functions2,"{"+string(i)+"},x)","]");
                    newobjectiveFcn2=str2func(functions2);
                    objectiveFcn2=@(x)newobjectiveFcn2(x,app.NNmodel);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
                if app.GAButton.Value == 1
                    try
                        rng default 
                        options = optimoptions('ga','PlotFcn', @gaplotbestf, PopulationSize=app.PopulationSizeSpinner_2.Value, MaxGenerations=app.MaxGenerationsSpinner_2.Value);

                        x = ga(objectiveFcn,app.OptNvars,[],[],[],[],app.LowerBound,app.UpperBound,[],app.IntegerPosition,options);

                        app.UITable10_3.ColumnName =  erase(app.input_names,"in_");
                        app.UITable10_3.RowName = "Best design";
                        app.UITable10_3.Data = x;

                        measure_val = objectiveFcn2(x);
                        app.UITable11_3.ColumnName =  erase(app.Output_names,"out_");
                        app.UITable11_3.RowName = "Best design";
                        app.UITable11_3.Data = measure_val;


                    catch ME
                        uialert(app.UIFigure,ME.message,"Error");
                    return;
                    end  
                end 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.PatternSearchButton.Value == 1 
                    try
                        rng default 
                        options = optimoptions('patternsearch','PlotFcn',@psplotbestf,'Algorithm', app.AlgorithmDropDown.Value, MaxIterations=app.MaxIterationsSpinner_2.Value);

                        x0 = (app.LowerBound + app.UpperBound)/2;

                        x = patternsearch(objectiveFcn,x0,[],[],[],[],app.LowerBound,app.UpperBound,[],options);

                        app.UITable10_4.ColumnName =  erase(app.input_names,"in_");
                        app.UITable10_4.RowName = "Best design";
                        app.UITable10_4.Data = x;

                        measure_val = objectiveFcn2(x);
                        app.UITable11_4.ColumnName =  erase(app.Output_names,"out_");
                        app.UITable11_4.RowName = "Best design";
                        app.UITable11_4.Data = measure_val;


                    catch ME
                        uialert(app.UIFigure,ME.message,"Error");
                    return;
                    end  
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.ParticleSwarmButton.Value == 1 
                    try
                        rng default 
                        options = optimoptions('particleswarm','SwarmSize', app.SwarmSizeSpinner.Value,'MaxIterations', app.MaxIterationsSpinner_3.Value,PlotFcn='pswplotbestf');

                        x = particleswarm(objectiveFcn,app.OptNvars,app.LowerBound,app.UpperBound,options);

                        app.UITable10_5.ColumnName =  erase(app.input_names,"in_");
                        app.UITable10_5.RowName = "Best design";
                        app.UITable10_5.Data = x;

                        measure_val = objectiveFcn2(x);
                        app.UITable11_5.ColumnName =  erase(app.Output_names,"out_");
                        app.UITable11_5.RowName = "Best design";
                        app.UITable11_5.Data = measure_val;


                    catch ME
                        uialert(app.UIFigure,ME.message,"Error");
                    return;
                    end  
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        % Button pushed function: LoadregressionmodelsIfyouhaveButton
        function LoadregressionmodelsIfyouhaveButtonPushed(app, event)

                    try
                    all_names = string(app.DataSet_train.Properties.VariableNames);
                    app.input_names = all_names(startsWith(all_names,"in_"));
                    app.Output_names = all_names(startsWith(all_names,"out_"));
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % prepare the data for each variable

                    Training_Data = app.DataSet_train.Variables;

                    app.input_Training_Data = Training_Data(:,startsWith(all_names,"in_"));
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    filterspec3 = {'*.mat'};  % specify the extension of the file

                        [file3,location3] = uigetfile(filterspec3); % open browse page
                        selectedExcelfile3 = fullfile(location3,file3);  % write the full path of the file with file name
                        selectedExcelfile13 = fullfile(location3,[]);  % write the full path of the file without file name
                        app.FilepathTextArea_3.Value= "User selected: "+fullfile(location3,file3);
                        addpath(selectedExcelfile13); % Add the path 
                        NNmodel2 = load(selectedExcelfile3);
                        app.NNmodel = NNmodel2.NNmodel;
                        app.NumberoftrainedmodelsEditField.Value = length(app.NNmodel);
                    catch ME
                        uialert(app.UIFigure,ME.message,"File Error");
                    return;
                    end  
        end

        % Button pushed function: SaveregressionresultsButton
        function SaveregressionresultsButtonPushed(app, event)
            filter5 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file5, location5] = uiputfile(filter5);
            selectedExcelfile12 = fullfile(location5,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile12);
            Table5 = cell2table(app.table_data,"RowNames",app.UITable.RowName,"VariableNames",app.UITable.ColumnName);
            writetable(Table5,file5);
        end

        % Button pushed function: SaveDecesionmatrixButton
        function SaveDecesionmatrixButtonPushed(app, event)
            filter6 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file6, location6] = uiputfile(filter6);
            selectedExcelfile16 = fullfile(location6,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile16);

            Table6 = array2table(app.UITable4.Data,"RowNames",app.UITable4.RowName,"VariableNames",app.UITable4.ColumnName);
            writetable(Table6,"Objectives "+file6);

            Table7 = array2table(app.UITable5.Data,"RowNames",app.UITable5.RowName,"VariableNames",app.UITable5.ColumnName);
            writetable(Table7,"Design variables "+file6);

        end

        % Button pushed function: SaveAHPweightsButton
        function SaveAHPweightsButtonPushed(app, event)
            filter8 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file8, location8] = uiputfile(filter8);
            selectedExcelfile8 = fullfile(location8,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile8);
            Table8 = array2table(app.UITable6.Data,"RowNames",app.UITable6.RowName,"VariableNames",{app.UITable6.ColumnName});
            writetable(Table8,file8);
        end

        % Button pushed function: SaveANPweightsButton
        function SaveANPweightsButtonPushed(app, event)
            filter9 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file9, location9] = uiputfile(filter9);
            selectedExcelfile9 = fullfile(location9,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile9);
            Table9 = array2table(app.UITable8.Data,"RowNames",app.UITable8.RowName,"VariableNames",{app.UITable8.ColumnName});
            writetable(Table9,file9);
        end

        % Button pushed function: SaveAHPTOPSISdataButton
        function SaveAHPTOPSISdataButtonPushed(app, event)
            filter10 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file10, location10] = uiputfile(filter10);
            selectedExcelfile10 = fullfile(location10,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile10);

            Table10 = app.Table_TOPSIS_AHP;
            Table10.Properties.VariableNames=app.UITable9.ColumnName;
            %array2table(app.UITable9.Data,"VariableNames",app.UITable9.ColumnName);
            writetable(Table10,"Ranking Designs "+file10);

            Table12 = array2table(app.UITable10.Data,"VariableNames",app.UITable10.ColumnName);
            writetable(Table12,"Best design variables "+file10);

            Table12 = array2table(app.UITable11.Data,"VariableNames",app.UITable11.ColumnName);
            writetable(Table12,"Best Measures "+file10);
        end

        % Button pushed function: SaveANPTOPSISdataButton
        function SaveANPTOPSISdataButtonPushed(app, event)
            filter11 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file11, location11] = uiputfile(filter11);
            selectedExcelfile11 = fullfile(location11,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile11);

            Table13 = app.Table_TOPSIS_ANP;
            Table13.Properties.VariableNames = app.UITable9_2.ColumnName;
            %array2table(app.UITable9_2.Data,"VariableNames",);
            writetable(Table13,"Ranking Designs "+file11);

            Table14 = array2table(app.UITable10_2.Data,"VariableNames",app.UITable10_2.ColumnName);
            writetable(Table14,"Best design variables "+file11);

            Table15 = array2table(app.UITable11_2.Data,"VariableNames",app.UITable11_2.ColumnName);
            writetable(Table15,"Best Measures "+file11);
        end

        % Button pushed function: SaveGAresultsButton
        function SaveGAresultsButtonPushed(app, event)
            filter6 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file6, location6] = uiputfile(filter6);
            selectedExcelfile16 = fullfile(location6,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile16);

            Table6 = array2table(app.UITable10_3.Data,"VariableNames",app.UITable10_3.ColumnName);
            writetable(Table6,"Best Design "+file6);

            Table7 = array2table(app.UITable11_3.Data,"VariableNames",app.UITable11_3.ColumnName);
            writetable(Table7,"Best Measures "+file6);
        end

        % Button pushed function: SavePatternsearchresultsButton
        function SavePatternsearchresultsButtonPushed(app, event)
            filter6 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file6, location6] = uiputfile(filter6);
            selectedExcelfile16 = fullfile(location6,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile16);

            Table6 = array2table(app.UITable10_4.Data,"VariableNames",app.UITable10_4.ColumnName);
            writetable(Table6,"Best Design "+file6);

            Table7 = array2table(app.UITable11_4.Data,"VariableNames",app.UITable11_4.ColumnName);
            writetable(Table7,"Best Measures "+file6);
        end

        % Button pushed function: SaveParticleswarmresultsButton
        function SaveParticleswarmresultsButtonPushed(app, event)
            filter6 = {'*.xlsx';
                       '*.slx';
                       '*.csv'}; 
            [file6, location6] = uiputfile(filter6);
            selectedExcelfile16 = fullfile(location6,[]);  % write the full path of the file without file name
            addpath(selectedExcelfile16);

            Table6 = array2table(app.UITable10_5.Data,"VariableNames",app.UITable10_5.ColumnName);
            writetable(Table6,"Best Design "+file6);

            Table7 = array2table(app.UITable11_5.Data,"VariableNames",app.UITable11_5.ColumnName);
            writetable(Table7,"Best Measures "+file6);
        end

        % Size changed function: SelectionTOPSISTab
        function SelectionTOPSISTabSizeChanged(app, event)
            position = app.SelectionTOPSISTab.Position;
            
        end

        % Cell edit callback: UITable9
        function UITable9CellEdit(app, event)
      
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.LoadingDatasetsPanel = uipanel(app.RegressionTab);
            app.LoadingDatasetsPanel.Title = 'Loading Data sets';
            app.Panel = uipanel(app.LoadingDatasetsPanel);
            app.DatasetsizeEditFieldLabel = uilabel(app.Panel);
            app.DatasetsizeEditFieldLabel.Text = 'Data set size';
            app.DatasetsizeEditField_1 = uieditfield(app.Panel, 'numeric');
            app.DatasetsizeEditField_1.Editable = 'off';
            app.NumberofcolumnsEditField_2Label = uilabel(app.Panel);
            app.NumberofcolumnsEditField_2Label.Text = 'Number of columns';
            app.NumberofcolumnsEditField_1 = uieditfield(app.Panel, 'numeric');
            app.NumberofcolumnsEditField_1.Editable = 'off';
            app.Panel_2 = uipanel(app.LoadingDatasetsPanel);
            app.DatasetsizeEditField_2Label = uilabel(app.Panel_2);
            app.DatasetsizeEditField_2Label.Text = 'Data set size';
            app.DatasetsizeEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.DatasetsizeEditField_2.Editable = 'off';
            app.NumberofcolumnsEditFieldLabel = uilabel(app.Panel_2);
            app.NumberofcolumnsEditFieldLabel.Text = 'Number of columns';
            app.NumberofcolumnsEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.NumberofcolumnsEditField_2.Editable = 'off';
            app.FilepathTextAreaLabel = uilabel(app.LoadingDatasetsPanel);
            app.FilepathTextAreaLabel.Text = 'File path ';
            app.FilepathTextArea = uitextarea(app.LoadingDatasetsPanel);
            app.FilepathTextArea.Editable = 'off';
            app.FilepathTextArea_2Label = uilabel(app.LoadingDatasetsPanel);
            app.FilepathTextArea_2Label.Text = 'File path ';
            app.FilepathTextArea_2 = uitextarea(app.LoadingDatasetsPanel);
            app.FilepathTextArea_2.Editable = 'off';
            app.LoadTrainingdataButton = uibutton(app.LoadingDatasetsPanel, 'push');
            app.LoadTrainingdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainingdataButtonPushed, true);
            app.LoadTrainingdataButton.Icon = fullfile(pathToMLAPP, 'data.png');
            app.LoadTrainingdataButton.Text = 'Load Training data';
            app.LoadTestingdataButton = uibutton(app.LoadingDatasetsPanel, 'push');
            app.LoadTestingdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestingdataButtonPushed, true);
            app.LoadTestingdataButton.Icon = fullfile(pathToMLAPP, 'data.png');
            app.LoadTestingdataButton.Text = 'Load Testing data';
            app.NoteTextAreaLabel = uilabel(app.LoadingDatasetsPanel);
            app.NoteTextAreaLabel.Text = 'Note:';
            app.NoteTextArea = uitextarea(app.LoadingDatasetsPanel);
            app.NoteTextArea.Editable = 'off';
            app.NoteTextArea.Value = {'Kindely, prepare the data in excel sheet and put the input variables at the begining (put in_ before the variable name) and the output measures at the end (put out_ before the measure name).'};
            app.ANNhyperparanetersoptimizationPanel = uipanel(app.RegressionTab);
            app.ANNhyperparanetersoptimizationPanel.Title = 'ANN hyperparaneters optimization';
            app.ResultsoftheoptimizationPanel = uipanel(app.ANNhyperparanetersoptimizationPanel);
            app.ResultsoftheoptimizationPanel.Title = 'Results of the optimization';
            app.StatusTextAreaLabel = uilabel(app.ResultsoftheoptimizationPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.ResultsoftheoptimizationPanel);
            app.StatusofoutputmeasureoptimizationGaugeLabel = uilabel(app.ResultsoftheoptimizationPanel);
            app.StatusofoutputmeasureoptimizationGaugeLabel.Text = 'Status of output measure optimization';
            app.StatusofoutputmeasureoptimizationGauge = uigauge(app.ResultsoftheoptimizationPanel, 'linear');
            app.StatusofoutputmeasureoptimizationGauge.Limits = [1 2];
            app.StatusofoutputmeasureoptimizationGauge.MinorTicks = [1 1.04 1.08 1.12 1.16 1.2 1.24 1.28 1.32 1.36 1.4 1.44 1.48 1.52 1.56 1.6 1.64 1.68 1.72 1.76 1.8 1.84 1.88 1.92 1.96 2 2.04 2.08 2.12 2.16 2.2 2.24 2.28 2.32 2.36 2.4 2.44 2.48 2.52 2.56 2.6 2.64 2.68 2.72 2.76 2.8 2.84 2.88 2.92 2.96 3 3.04 3.08 3.12 3.16 3.2 3.24 3.28 3.32 3.36 3.4 3.44 3.48 3.52 3.56 3.6 3.64 3.68 3.72 3.76 3.8 3.84 3.88 3.92 3.96 4 4.04 4.08 4.12 4.16 4.2 4.24 4.28 4.32 4.36 4.4 4.44 4.48 4.52 4.56 4.6 4.64 4.68 4.72 4.76 4.8 4.84 4.88 4.92 4.96 5 5.04 5.08 5.12 5.16 5.2 5.24 5.28 5.32 5.36 5.4 5.44 5.48 5.52 5.56 5.6 5.64 5.68 5.72 5.76 5.8 5.84 5.88 5.92 5.96 6];
            app.StatusofoutputmeasureoptimizationGauge.Value = 1;
            app.UITable = uitable(app.ResultsoftheoptimizationPanel);
            app.UITable.ColumnName = {'Layer sizes'; 'Activation function'; 'Layer weights initializer'; 'Layer biases initializer'; 'lambda'; 'Standrization'; 'Training RMS'; 'Testing RMSE'};
            app.UITable.RowName = {};
            app.SaveregressionresultsButton = uibutton(app.ResultsoftheoptimizationPanel, 'push');
            app.SaveregressionresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveregressionresultsButtonPushed, true);
            app.SaveregressionresultsButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveregressionresultsButton.Text = 'Save regression results';
            app.NumberofevaluationsSpinnerLabel = uilabel(app.ANNhyperparanetersoptimizationPanel);
            app.NumberofevaluationsSpinnerLabel.Text = 'Number of evaluations';
            app.NumberofevaluationsSpinner = uispinner(app.ANNhyperparanetersoptimizationPanel);
            app.NumberofevaluationsSpinner.Limits = [1 Inf];
            app.NumberofevaluationsSpinner.RoundFractionalValues = 'on';
            app.NumberofevaluationsSpinner.Value = 1;
            app.OptimizedhyperparametersPanel = uipanel(app.ANNhyperparanetersoptimizationPanel);
            app.OptimizedhyperparametersPanel.Title = 'Optimized hyperparameters ';
            app.ActivationfunctionCheckBox = uicheckbox(app.OptimizedhyperparametersPanel);
            app.ActivationfunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivationfunctionCheckBoxValueChanged, true);
            app.ActivationfunctionCheckBox.Text = 'Activation function';
            app.LayersweightsCheckBox = uicheckbox(app.OptimizedhyperparametersPanel);
            app.LayersweightsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LayersweightsCheckBoxValueChanged, true);
            app.LayersweightsCheckBox.Text = 'Layers weights';
            app.LayerbiasesCheckBox = uicheckbox(app.OptimizedhyperparametersPanel);
            app.LayerbiasesCheckBox.ValueChangedFcn = createCallbackFcn(app, @LayerbiasesCheckBoxValueChanged, true);
            app.LayerbiasesCheckBox.Text = 'Layer biases';
            app.RegularizationLambdaCheckBox = uicheckbox(app.OptimizedhyperparametersPanel);
            app.RegularizationLambdaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegularizationLambdaCheckBoxValueChanged, true);
            app.RegularizationLambdaCheckBox.Text = 'Regularization (Lambda)';
            app.StandrizationCheckBox = uicheckbox(app.OptimizedhyperparametersPanel);
            app.StandrizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @StandrizationCheckBoxValueChanged, true);
            app.StandrizationCheckBox.Text = 'Standrization';
            app.LayersrangeSpinnerLabel = uilabel(app.OptimizedhyperparametersPanel);
            app.LayersrangeSpinnerLabel.Visible = 'off';
            app.LayersrangeSpinnerLabel.Text = 'Layers range';
            app.LayersrangeSpinner = uispinner(app.OptimizedhyperparametersPanel);
            app.LayersrangeSpinner.Limits = [1 4];
            app.LayersrangeSpinner.Visible = 'off';
            app.LayersrangeSpinner.Value = 1;
            app.NumberofhiddenlayersCheckBox = uicheckbox(app.OptimizedhyperparametersPanel);
            app.NumberofhiddenlayersCheckBox.ValueChangedFcn = createCallbackFcn(app, @NumberofhiddenlayersCheckBoxValueChanged, true);
            app.NumberofhiddenlayersCheckBox.Text = 'Number of hidden layers';
            app.Lamp = uilamp(app.OptimizedhyperparametersPanel);
            app.Lamp_2 = uilamp(app.OptimizedhyperparametersPanel);
            app.Lamp_3 = uilamp(app.OptimizedhyperparametersPanel);
            app.Lamp_4 = uilamp(app.OptimizedhyperparametersPanel);
            app.Lamp_5 = uilamp(app.OptimizedhyperparametersPanel);
            app.Lamp_6 = uilamp(app.OptimizedhyperparametersPanel);
            app.toSpinnerLabel = uilabel(app.OptimizedhyperparametersPanel);
            app.toSpinnerLabel.Visible = 'off';
            app.toSpinnerLabel.Text = 'to';
            app.toSpinner = uispinner(app.OptimizedhyperparametersPanel);
            app.toSpinner.Limits = [2 5];
            app.toSpinner.Visible = 'off';
            app.toSpinner.Value = 2;
            app.StarttheoptimizationprocessButton = uibutton(app.ANNhyperparanetersoptimizationPanel, 'push');
            app.StarttheoptimizationprocessButton.ButtonPushedFcn = createCallbackFcn(app, @StarttheoptimizationprocessButtonPushed, true);
            app.StarttheoptimizationprocessButton.Icon = fullfile(pathToMLAPP, 'HO.png');
            app.StarttheoptimizationprocessButton.Text = 'Start the optimization process';
            app.HiddenlayersPanel = uipanel(app.ANNhyperparanetersoptimizationPanel);
            app.HiddenlayersPanel.Title = 'Hidden layers';
            app.LayerssizesSpinnerLabel = uilabel(app.HiddenlayersPanel);
            app.LayerssizesSpinnerLabel.Text = 'Layers sizes';
            app.LayerssizesSpinner = uispinner(app.HiddenlayersPanel);
            app.LayerssizesSpinner.Limits = [1 5];
            app.LayerssizesSpinner.Value = 1;
            app.Layer5SpinnerLabel = uilabel(app.HiddenlayersPanel);
            app.Layer5SpinnerLabel.Text = 'Layer 5';
            app.Layer5Spinner = uispinner(app.HiddenlayersPanel);
            app.Layer5Spinner.Limits = [1 1000];
            app.Layer5Spinner.RoundFractionalValues = 'on';
            app.Layer5Spinner.Value = 1;
            app.Layer1SpinnerLabel = uilabel(app.HiddenlayersPanel);
            app.Layer1SpinnerLabel.Text = 'Layer 1';
            app.Layer1Spinner = uispinner(app.HiddenlayersPanel);
            app.Layer1Spinner.Limits = [1 1000];
            app.Layer1Spinner.RoundFractionalValues = 'on';
            app.Layer1Spinner.Value = 1;
            app.Layer2Label = uilabel(app.HiddenlayersPanel);
            app.Layer2Label.Text = 'Layer 2';
            app.Layer2Spinner = uispinner(app.HiddenlayersPanel);
            app.Layer2Spinner.Limits = [1 1000];
            app.Layer2Spinner.RoundFractionalValues = 'on';
            app.Layer2Spinner.Value = 1;
            app.Layer3SpinnerLabel = uilabel(app.HiddenlayersPanel);
            app.Layer3SpinnerLabel.Text = 'Layer 3';
            app.Layer3Spinner = uispinner(app.HiddenlayersPanel);
            app.Layer3Spinner.Limits = [1 1000];
            app.Layer3Spinner.RoundFractionalValues = 'on';
            app.Layer3Spinner.Value = 1;
            app.Layer4SpinnerLabel = uilabel(app.HiddenlayersPanel);
            app.Layer4SpinnerLabel.Text = 'Layer 4';
            app.Layer4Spinner = uispinner(app.HiddenlayersPanel);
            app.Layer4Spinner.Limits = [1 1000];
            app.Layer4Spinner.RoundFractionalValues = 'on';
            app.Layer4Spinner.Value = 1;
            app.Layer1Switch = uiswitch(app.HiddenlayersPanel, 'slider');
            app.Layer1Switch.ValueChangedFcn = createCallbackFcn(app, @Layer1SwitchValueChanged, true);
            app.Layer2Switch = uiswitch(app.HiddenlayersPanel, 'slider');
            app.Layer2Switch.ValueChangedFcn = createCallbackFcn(app, @Layer2SwitchValueChanged, true);
            app.Layer3Switch = uiswitch(app.HiddenlayersPanel, 'slider');
            app.Layer3Switch.ValueChangedFcn = createCallbackFcn(app, @Layer3SwitchValueChanged, true);
            app.Layer4Switch = uiswitch(app.HiddenlayersPanel, 'slider');
            app.Layer4Switch.ValueChangedFcn = createCallbackFcn(app, @Layer4SwitchValueChanged, true);
            app.Layer5Switch = uiswitch(app.HiddenlayersPanel, 'slider');
            app.Layer5Switch.ValueChangedFcn = createCallbackFcn(app, @Layer5SwitchValueChanged, true);
            app.LampLa_1 = uilamp(app.HiddenlayersPanel);
            app.LampLa_2 = uilamp(app.HiddenlayersPanel);
            app.LampLa_3 = uilamp(app.HiddenlayersPanel);
            app.LampLa_4 = uilamp(app.HiddenlayersPanel);
            app.LampLa_5 = uilamp(app.HiddenlayersPanel);
            app.MultiobjectiveoptimizationTab = uitab(app.TabGroup);
            app.MultiobjectiveoptimizationTab.Title = 'Multi-objective optimization';
            app.MultiObjectiveOptimizationalgorithmButtonGroup = uibuttongroup(app.MultiobjectiveoptimizationTab);
            app.MultiObjectiveOptimizationalgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MultiObjectiveOptimizationalgorithmButtonGroupSelectionChanged, true);
            app.MultiObjectiveOptimizationalgorithmButtonGroup.Title = 'Multi-Objective Optimization algorithm';
            app.MultiObjectiveOptimizationalgorithmButtonGroup.ButtonDownFcn = createCallbackFcn(app, @MultiObjectiveOptimizationalgorithmButtonGroupButtonDown, true);
            app.NSGAIIButton = uitogglebutton(app.MultiObjectiveOptimizationalgorithmButtonGroup);
            app.NSGAIIButton.Icon = fullfile(pathToMLAPP, 'NSGA-II.jpg');
            app.NSGAIIButton.Text = 'NSGA-II';
            app.NSGAIIButton.Value = true;
            app.ParetosearchButton = uitogglebutton(app.MultiObjectiveOptimizationalgorithmButtonGroup);
            app.ParetosearchButton.Icon = fullfile(pathToMLAPP, 'pareto.png');
            app.ParetosearchButton.Text = 'Pareto search';
            app.Lamp_7 = uilamp(app.MultiObjectiveOptimizationalgorithmButtonGroup);
            app.Lamp_8 = uilamp(app.MultiObjectiveOptimizationalgorithmButtonGroup);
            app.SPEA2Button = uitogglebutton(app.MultiObjectiveOptimizationalgorithmButtonGroup);
            app.SPEA2Button.Icon = fullfile(pathToMLAPP, 'SPEA2.png');
            app.SPEA2Button.Text = 'SPEA-2';
            app.Lamp_12 = uilamp(app.MultiObjectiveOptimizationalgorithmButtonGroup);
            app.OptimizeandgettheresultsButton = uibutton(app.MultiObjectiveOptimizationalgorithmButtonGroup, 'push');
            app.OptimizeandgettheresultsButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeandgettheresultsButtonPushed, true);
            app.OptimizeandgettheresultsButton.Icon = fullfile(pathToMLAPP, 'solve.png');
            app.OptimizeandgettheresultsButton.Text = 'Optimize and get the results';
            app.SaveDecesionmatrixButton = uibutton(app.MultiObjectiveOptimizationalgorithmButtonGroup, 'push');
            app.SaveDecesionmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDecesionmatrixButtonPushed, true);
            app.SaveDecesionmatrixButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveDecesionmatrixButton.Text = 'Save Decesion matrix';
            app.InputdatapreparationPanel = uipanel(app.MultiobjectiveoptimizationTab);
            app.InputdatapreparationPanel.Title = 'Input data preparation';
            app.ObjectivesPanel = uipanel(app.InputdatapreparationPanel);
            app.ObjectivesPanel.Title = 'Objectives';
            app.UITable2 = uitable(app.ObjectivesPanel);
            app.UITable2.ColumnName = {'Maximize'; 'Minimize'};
            app.UITable2.RowName = {};
            app.DesignvariablesPanel = uipanel(app.InputdatapreparationPanel);
            app.DesignvariablesPanel.Title = 'Design variables';
            app.UITable3 = uitable(app.DesignvariablesPanel);
            app.UITable3.ColumnName = {'Lower bound'; 'Upper bound'; 'Integer'};
            app.UITable3.RowName = {};
            app.ExtractinformationButton = uibutton(app.InputdatapreparationPanel, 'push');
            app.ExtractinformationButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractinformationButtonPushed, true);
            app.ExtractinformationButton.Icon = fullfile(pathToMLAPP, 'extract.png');
            app.ExtractinformationButton.Text = 'Extract information';
            app.LoadregressionmodelsIfyouhaveButton = uibutton(app.InputdatapreparationPanel, 'push');
            app.LoadregressionmodelsIfyouhaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadregressionmodelsIfyouhaveButtonPushed, true);
            app.LoadregressionmodelsIfyouhaveButton.Icon = fullfile(pathToMLAPP, 'load.png');
            app.LoadregressionmodelsIfyouhaveButton.Text = 'Load regression models (If you have)';
            app.FilepathTextArea_3Label = uilabel(app.InputdatapreparationPanel);
            app.FilepathTextArea_3Label.Text = 'File path ';
            app.FilepathTextArea_3 = uitextarea(app.InputdatapreparationPanel);
            app.FilepathTextArea_3.Editable = 'off';
            app.NumberoftrainedmodelsEditFieldLabel = uilabel(app.InputdatapreparationPanel);
            app.NumberoftrainedmodelsEditFieldLabel.Text = 'Number of trained models';
            app.NumberoftrainedmodelsEditField = uieditfield(app.InputdatapreparationPanel, 'numeric');
            app.OptimizationResultsPanel = uipanel(app.MultiobjectiveoptimizationTab);
            app.OptimizationResultsPanel.Title = 'Optimization Results';
            app.DecesionmatrixObjectivesPanel = uipanel(app.OptimizationResultsPanel);
            app.DecesionmatrixObjectivesPanel.Title = 'Decesion matrix (Objectives )';
            app.UITable4 = uitable(app.DecesionmatrixObjectivesPanel);
            app.UITable4.ColumnName = {'Design'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.DecesionmatrixDesignvariablesPanel = uipanel(app.OptimizationResultsPanel);
            app.DecesionmatrixDesignvariablesPanel.Title = 'Decesion matrix (Design variables )';
            app.UITable5 = uitable(app.DecesionmatrixDesignvariablesPanel);
            app.UITable5.ColumnName = {'Design'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable5.RowName = {};
            app.OptimizationparametersPanel = uipanel(app.MultiobjectiveoptimizationTab);
            app.OptimizationparametersPanel.Title = 'Optimization parameters';
            app.NSGAIIPanel = uipanel(app.OptimizationparametersPanel);
            app.NSGAIIPanel.Title = 'NSGA-II';
            app.MaxGenerationsLabel = uilabel(app.NSGAIIPanel);
            app.MaxGenerationsLabel.Text = 'Max Generations  ';
            app.MaxGenerationsSpinner = uispinner(app.NSGAIIPanel);
            app.PopulationSizeLabel = uilabel(app.NSGAIIPanel);
            app.PopulationSizeLabel.Text = 'Population Size ';
            app.PopulationSizeSpinner = uispinner(app.NSGAIIPanel);
            app.TextArea = uitextarea(app.NSGAIIPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'(default) 200*number Of Variables.'};
            app.TextArea_2 = uitextarea(app.NSGAIIPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'(default) 50 (Variables <= 5) or 200 otherwise.'};
            app.ParetosearchPanel = uipanel(app.OptimizationparametersPanel);
            app.ParetosearchPanel.Title = 'Pareto search';
            app.ParetoSetSizeSpinnerLabel = uilabel(app.ParetosearchPanel);
            app.ParetoSetSizeSpinnerLabel.Text = 'Pareto Set Size';
            app.ParetoSetSizeSpinner = uispinner(app.ParetosearchPanel);
            app.TextArea_3 = uitextarea(app.ParetosearchPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'(default) max of (number Of Objectives, 60)'};
            app.MaxIterationsSpinnerLabel = uilabel(app.ParetosearchPanel);
            app.MaxIterationsSpinnerLabel.Text = 'Max Iterations';
            app.MaxIterationsSpinner = uispinner(app.ParetosearchPanel);
            app.TextArea_4 = uitextarea(app.ParetosearchPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'(default) 100*(# Variables + #Objectives)'};
            app.SPEA2Panel = uipanel(app.OptimizationparametersPanel);
            app.SPEA2Panel.Title = 'SPEA-2';
            app.PopulationSizeSpinner_3Label = uilabel(app.SPEA2Panel);
            app.PopulationSizeSpinner_3Label.Text = 'Population Size ';
            app.PopulationSizeSpinner_3 = uispinner(app.SPEA2Panel);
            app.TextArea_11 = uitextarea(app.SPEA2Panel);
            app.TextArea_11.Editable = 'off';
            app.TextArea_11.Value = {'(default) max of (number Of Objectives, 60)'};
            app.MaxIterationsSpinner_4Label = uilabel(app.SPEA2Panel);
            app.MaxIterationsSpinner_4Label.Text = 'Max Iterations';
            app.MaxIterationsSpinner_4 = uispinner(app.SPEA2Panel);
            app.TextArea_12 = uitextarea(app.SPEA2Panel);
            app.TextArea_12.Editable = 'off';
            app.TextArea_12.Value = {'(default) 100*(# Variables + #Objectives)'};
            app.PopulationSizeSpinner_3Label_2 = uilabel(app.SPEA2Panel);
            app.PopulationSizeSpinner_3Label_2.Text = 'Archive Size ';
            app.PopulationSizeSpinner_4 = uispinner(app.SPEA2Panel);
            app.TextArea_13 = uitextarea(app.SPEA2Panel);
            app.TextArea_13.Editable = 'off';
            app.TextArea_13.Value = {'(default) max of (number Of Objectives, 40)'};
            app.WeightingofperformancemeasuresTab = uitab(app.TabGroup);
            app.WeightingofperformancemeasuresTab.Title = 'Weighting of performance measures';
            app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel = uipanel(app.WeightingofperformancemeasuresTab);
            app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel.Title = 'Weighting performance measures using analytic hierarchy process (AHP)';
            app.NumberofexpertsSpinnerLabel = uilabel(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.NumberofexpertsSpinnerLabel.Text = 'Number of experts';
            app.NumberofexpertsSpinner = uispinner(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.GeneratepairwisecomparisonmatrixButton = uibutton(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel, 'push');
            app.GeneratepairwisecomparisonmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratepairwisecomparisonmatrixButtonPushed, true);
            app.GeneratepairwisecomparisonmatrixButton.Icon = fullfile(pathToMLAPP, 'matrix.png');
            app.GeneratepairwisecomparisonmatrixButton.Text = 'Generate pairwise comparison matrix';
            app.ExpertspairwisecomparisonmatricesPanel = uipanel(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.ExpertspairwisecomparisonmatricesPanel.Title = 'Experts pairwise comparison matrices';
            app.TabGroup2 = uitabgroup(app.ExpertspairwisecomparisonmatricesPanel);
            app.Expert1Tab = uitab(app.TabGroup2);
            app.Expert1Tab.Title = 'Expert (1)';
            app.Note1TextAreaLabel = uilabel(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.Note1TextAreaLabel.Text = 'Note1';
            app.Note1TextArea = uitextarea(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.Note1TextArea.Editable = 'off';
            app.Note1TextArea.Value = {'Please, input the number of experts the pairwise comparison matrix to fill it by the experts.'; 'Please fill the matrix based on satty scale (1 to 9).'};
            app.ResultsPanel = uipanel(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.ResultsPanel.Title = 'Results';
            app.MeasuresweightsPanel = uipanel(app.ResultsPanel);
            app.MeasuresweightsPanel.Title = 'Measures weights';
            app.UITable6 = uitable(app.MeasuresweightsPanel);
            app.UITable6.ColumnName = {'Measures'};
            app.UITable6.RowName = {};
            app.ConsistencyratioCRPanel = uipanel(app.ResultsPanel);
            app.ConsistencyratioCRPanel.Title = 'Consistency ratio (CR)';
            app.UITable7 = uitable(app.ConsistencyratioCRPanel);
            app.UITable7.ColumnName = {'CR'};
            app.UITable7.RowName = {};
            app.CalculateAHPweightsButton = uibutton(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel, 'push');
            app.CalculateAHPweightsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAHPweightsButtonPushed, true);
            app.CalculateAHPweightsButton.Icon = fullfile(pathToMLAPP, 'weight.png');
            app.CalculateAHPweightsButton.Text = 'Calculate AHP weights';
            app.Note2TextAreaLabel = uilabel(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.Note2TextAreaLabel.Text = 'Note2';
            app.Note2TextArea = uitextarea(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel);
            app.Note2TextArea.Editable = 'off';
            app.Note2TextArea.Value = {'For ANP, please assign value from 0 to 1 at each cell to indicate the infeluence of element on the left on an element of the top.'};
            app.SaveAHPweightsButton = uibutton(app.WeightingperformancemeasuresusinganalyticnetworkprocessANPPanel, 'push');
            app.SaveAHPweightsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAHPweightsButtonPushed, true);
            app.SaveAHPweightsButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveAHPweightsButton.Text = 'Save AHP weights';
            app.GettingPanel = uipanel(app.WeightingofperformancemeasuresTab);
            app.GettingPanel.Title = 'Weighting performance measures using analytic network process (ANP)';
            app.PairwisecomparisonsforinterdependanceamongcriteriaPanel = uipanel(app.GettingPanel);
            app.PairwisecomparisonsforinterdependanceamongcriteriaPanel.Title = 'Pairwise comparisons for interdependance among criteria';
            app.TabGroup3 = uitabgroup(app.PairwisecomparisonsforinterdependanceamongcriteriaPanel);
            app.Expert1Tab_2 = uitab(app.TabGroup3);
            app.Expert1Tab_2.Title = 'Expert (1)';
            app.ResultsPanel_2 = uipanel(app.GettingPanel);
            app.ResultsPanel_2.Title = 'Results';
            app.UITable8 = uitable(app.ResultsPanel_2);
            app.UITable8.ColumnName = {'Measures'};
            app.UITable8.RowName = {};
            app.CalculateANPweightsButton = uibutton(app.GettingPanel, 'push');
            app.CalculateANPweightsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateANPweightsButtonPushed, true);
            app.CalculateANPweightsButton.Icon = fullfile(pathToMLAPP, 'ANP.png');
            app.CalculateANPweightsButton.Text = 'Calculate ANP weights';
            app.SaveANPweightsButton = uibutton(app.GettingPanel, 'push');
            app.SaveANPweightsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveANPweightsButtonPushed, true);
            app.SaveANPweightsButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveANPweightsButton.Text = 'Save ANP weights';
            app.SelectionTOPSISTab = uitab(app.TabGroup);
            app.SelectionTOPSISTab.SizeChangedFcn = createCallbackFcn(app, @SelectionTOPSISTabSizeChanged, true);
            app.SelectionTOPSISTab.Title = 'Selection - TOPSIS';
            app.DeceionmakingMultiobjectiveTOPSISUsingAHPPanel = uipanel(app.SelectionTOPSISTab);
            app.DeceionmakingMultiobjectiveTOPSISUsingAHPPanel.Title = 'Deceion making (Multi objective) - TOPSIS (Using AHP)';
            app.RankdesignswithTOPSISandselectthebestdesignButton = uibutton(app.DeceionmakingMultiobjectiveTOPSISUsingAHPPanel, 'push');
            app.RankdesignswithTOPSISandselectthebestdesignButton.ButtonPushedFcn = createCallbackFcn(app, @RankdesignswithTOPSISandselectthebestdesignButtonPushed, true);
            app.RankdesignswithTOPSISandselectthebestdesignButton.Icon = fullfile(pathToMLAPP, 'topsis.png');
            app.RankdesignswithTOPSISandselectthebestdesignButton.Text = 'Rank designs with TOPSIS and select the best design';
            app.RankingthedesignsPanel = uipanel(app.DeceionmakingMultiobjectiveTOPSISUsingAHPPanel);
            app.RankingthedesignsPanel.Title = 'Ranking the designs';
            app.UITable9 = uitable(app.RankingthedesignsPanel);
            app.UITable9.ColumnName = {'Designs'};
            app.UITable9.RowName = {};
            app.UITable9.CellEditCallback = createCallbackFcn(app, @UITable9CellEdit, true);
            app.BestdesignvariablesPanel = uipanel(app.DeceionmakingMultiobjectiveTOPSISUsingAHPPanel);
            app.BestdesignvariablesPanel.Title = 'Best design variables';
            app.UITable10 = uitable(app.BestdesignvariablesPanel);
            app.UITable10.ColumnName = {'Best design'};
            app.UITable10.RowName = {};
            app.BestdesignperformancemeasuresPanel = uipanel(app.DeceionmakingMultiobjectiveTOPSISUsingAHPPanel);
            app.BestdesignperformancemeasuresPanel.Title = 'Best design performance measures';
            app.UITable11 = uitable(app.BestdesignperformancemeasuresPanel);
            app.UITable11.ColumnName = {'Best measures'};
            app.UITable11.RowName = {};
            app.SaveAHPTOPSISdataButton = uibutton(app.DeceionmakingMultiobjectiveTOPSISUsingAHPPanel, 'push');
            app.SaveAHPTOPSISdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAHPTOPSISdataButtonPushed, true);
            app.SaveAHPTOPSISdataButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveAHPTOPSISdataButton.Text = 'Save AHP-TOPSIS data';
            app.DeceionmakingSingleobjectiveTOPSISUsingANPPanel = uipanel(app.SelectionTOPSISTab);
            app.DeceionmakingSingleobjectiveTOPSISUsingANPPanel.Title = 'Deceion making (Single objective) - TOPSIS (Using ANP)';
            app.RankdesignswithTOPSISandselectthebestdesignButton_2 = uibutton(app.DeceionmakingSingleobjectiveTOPSISUsingANPPanel, 'push');
            app.RankdesignswithTOPSISandselectthebestdesignButton_2.ButtonPushedFcn = createCallbackFcn(app, @RankdesignswithTOPSISandselectthebestdesignButton_2Pushed, true);
            app.RankdesignswithTOPSISandselectthebestdesignButton_2.Icon = fullfile(pathToMLAPP, 'topsis.png');
            app.RankdesignswithTOPSISandselectthebestdesignButton_2.Text = 'Rank designs with TOPSIS and select the best design';
            app.RankingthedesignsPanel_2 = uipanel(app.DeceionmakingSingleobjectiveTOPSISUsingANPPanel);
            app.RankingthedesignsPanel_2.Title = 'Ranking the designs';
            app.UITable9_2 = uitable(app.RankingthedesignsPanel_2);
            app.UITable9_2.ColumnName = {'Designs'};
            app.UITable9_2.RowName = {};
            app.BestdesignvariablesPanel_2 = uipanel(app.DeceionmakingSingleobjectiveTOPSISUsingANPPanel);
            app.BestdesignvariablesPanel_2.Title = 'Best design variables';
            app.UITable10_2 = uitable(app.BestdesignvariablesPanel_2);
            app.UITable10_2.ColumnName = {'Best design'};
            app.UITable10_2.RowName = {};
            app.BestdesignperformancemeasuresPanel_2 = uipanel(app.DeceionmakingSingleobjectiveTOPSISUsingANPPanel);
            app.BestdesignperformancemeasuresPanel_2.Title = 'Best design performance measures';
            app.UITable11_2 = uitable(app.BestdesignperformancemeasuresPanel_2);
            app.UITable11_2.ColumnName = {'Best measures'};
            app.UITable11_2.RowName = {};
            app.SaveANPTOPSISdataButton = uibutton(app.DeceionmakingSingleobjectiveTOPSISUsingANPPanel, 'push');
            app.SaveANPTOPSISdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveANPTOPSISdataButtonPushed, true);
            app.SaveANPTOPSISdataButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveANPTOPSISdataButton.Text = 'Save ANP-TOPSIS data';
            app.SelectionSingleobjectiveoptimizationTab = uitab(app.TabGroup);
            app.SelectionSingleobjectiveoptimizationTab.Title = 'Selection - Single-objective optimization';
            app.SingleobjectiveoptimizationButtonGroup = uibuttongroup(app.SelectionSingleobjectiveoptimizationTab);
            app.SingleobjectiveoptimizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SingleobjectiveoptimizationButtonGroupSelectionChanged, true);
            app.SingleobjectiveoptimizationButtonGroup.Title = 'Single objective optimization';
            app.GAButton = uitogglebutton(app.SingleobjectiveoptimizationButtonGroup);
            app.GAButton.Icon = fullfile(pathToMLAPP, 'GA.png');
            app.GAButton.Text = 'GA';
            app.GAButton.Value = true;
            app.PatternSearchButton = uitogglebutton(app.SingleobjectiveoptimizationButtonGroup);
            app.PatternSearchButton.Icon = fullfile(pathToMLAPP, 'pattern.png');
            app.PatternSearchButton.Text = 'Pattern Search';
            app.ParticleSwarmButton = uitogglebutton(app.SingleobjectiveoptimizationButtonGroup);
            app.ParticleSwarmButton.Icon = fullfile(pathToMLAPP, 'particle.png');
            app.ParticleSwarmButton.Text = 'Particle Swarm';
            app.Lamp_9 = uilamp(app.SingleobjectiveoptimizationButtonGroup);
            app.Lamp_10 = uilamp(app.SingleobjectiveoptimizationButtonGroup);
            app.Lamp_11 = uilamp(app.SingleobjectiveoptimizationButtonGroup);
            app.WeightingalgorithmSwitchLabel = uilabel(app.SingleobjectiveoptimizationButtonGroup);
            app.WeightingalgorithmSwitchLabel.Text = 'Weighting algorithm';
            app.WeightingalgorithmSwitch = uiswitch(app.SingleobjectiveoptimizationButtonGroup, 'slider');
            app.WeightingalgorithmSwitch.Items = {'AHP', 'ANP'};
            app.WeightingalgorithmSwitch.Value = 'AHP';
            app.OptimizationparametersPanel_2 = uipanel(app.SelectionSingleobjectiveoptimizationTab);
            app.OptimizationparametersPanel_2.Title = 'Optimization parameters';
            app.GAPanel = uipanel(app.OptimizationparametersPanel_2);
            app.GAPanel.Title = 'GA';
            app.MaxGenerationsSpinner_2Label = uilabel(app.GAPanel);
            app.MaxGenerationsSpinner_2Label.Text = 'Max Generations  ';
            app.MaxGenerationsSpinner_2 = uispinner(app.GAPanel);
            app.PopulationSizeSpinner_2Label = uilabel(app.GAPanel);
            app.PopulationSizeSpinner_2Label.Text = 'Population Size ';
            app.PopulationSizeSpinner_2 = uispinner(app.GAPanel);
            app.TextArea_5 = uitextarea(app.GAPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'(default) 100*number Of Variables.'};
            app.TextArea_6 = uitextarea(app.GAPanel);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'(default) 50 (Variables <= 5) or 200 otherwise.'};
            app.PatternsearchPanel = uipanel(app.OptimizationparametersPanel_2);
            app.PatternsearchPanel.Title = 'Pattern search';
            app.MaxIterationsSpinner_2Label = uilabel(app.PatternsearchPanel);
            app.MaxIterationsSpinner_2Label.Text = 'Max Iterations';
            app.MaxIterationsSpinner_2 = uispinner(app.PatternsearchPanel);
            app.TextArea_8 = uitextarea(app.PatternsearchPanel);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'(default) 100*number Of Variables'};
            app.AlgorithmDropDownLabel = uilabel(app.PatternsearchPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.PatternsearchPanel);
            app.AlgorithmDropDown.Items = {'classic', 'nups', 'nups-gps', 'nups-mads'};
            app.AlgorithmDropDown.Value = 'classic';
            app.ParicleawarmPanel = uipanel(app.OptimizationparametersPanel_2);
            app.ParicleawarmPanel.Title = 'Paricle awarm';
            app.MaxIterationsSpinner_3Label = uilabel(app.ParicleawarmPanel);
            app.MaxIterationsSpinner_3Label.Text = 'Max Iterations';
            app.MaxIterationsSpinner_3 = uispinner(app.ParicleawarmPanel);
            app.SwarmSizeSpinnerLabel = uilabel(app.ParicleawarmPanel);
            app.SwarmSizeSpinnerLabel.Text = 'Swarm Size';
            app.SwarmSizeSpinner = uispinner(app.ParicleawarmPanel);
            app.TextArea_9 = uitextarea(app.ParicleawarmPanel);
            app.TextArea_9.Editable = 'off';
            app.TextArea_9.Value = {'(default) 200*number Of Variables.'};
            app.TextArea_10 = uitextarea(app.ParicleawarmPanel);
            app.TextArea_10.Editable = 'off';
            app.TextArea_10.Value = {'(default) min(100,10*# variables)'};
            app.GAPanel_2 = uipanel(app.SelectionSingleobjectiveoptimizationTab);
            app.GAPanel_2.Title = 'GA';
            app.BestdesignvariablesPanel_3 = uipanel(app.GAPanel_2);
            app.BestdesignvariablesPanel_3.Title = 'Best design variables';
            app.UITable10_3 = uitable(app.BestdesignvariablesPanel_3);
            app.UITable10_3.ColumnName = {'Best design'};
            app.UITable10_3.RowName = {};
            app.BestdesignperformancemeasuresPanel_3 = uipanel(app.GAPanel_2);
            app.BestdesignperformancemeasuresPanel_3.Title = 'Best design performance measures';
            app.UITable11_3 = uitable(app.BestdesignperformancemeasuresPanel_3);
            app.UITable11_3.ColumnName = {'Best measures'};
            app.UITable11_3.RowName = {};
            app.SaveGAresultsButton = uibutton(app.GAPanel_2, 'push');
            app.SaveGAresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGAresultsButtonPushed, true);
            app.SaveGAresultsButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveGAresultsButton.Text = 'Save GA results';
            app.PatternSearchPanel = uipanel(app.SelectionSingleobjectiveoptimizationTab);
            app.PatternSearchPanel.Title = 'Pattern Search';
            app.BestdesignvariablesPanel_4 = uipanel(app.PatternSearchPanel);
            app.BestdesignvariablesPanel_4.Title = 'Best design variables';
            app.UITable10_4 = uitable(app.BestdesignvariablesPanel_4);
            app.UITable10_4.ColumnName = {'Best design'};
            app.UITable10_4.RowName = {};
            app.BestdesignperformancemeasuresPanel_4 = uipanel(app.PatternSearchPanel);
            app.BestdesignperformancemeasuresPanel_4.Title = 'Best design performance measures';
            app.UITable11_4 = uitable(app.BestdesignperformancemeasuresPanel_4);
            app.UITable11_4.ColumnName = {'Best measures'};
            app.UITable11_4.RowName = {};
            app.SavePatternsearchresultsButton = uibutton(app.PatternSearchPanel, 'push');
            app.SavePatternsearchresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePatternsearchresultsButtonPushed, true);
            app.SavePatternsearchresultsButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SavePatternsearchresultsButton.Text = 'Save Pattern search results';
            app.ParticleSwarmPanel = uipanel(app.SelectionSingleobjectiveoptimizationTab);
            app.ParticleSwarmPanel.Title = 'Particle Swarm';
            app.BestdesignvariablesPanel_5 = uipanel(app.ParticleSwarmPanel);
            app.BestdesignvariablesPanel_5.Title = 'Best design variables';
            app.UITable10_5 = uitable(app.BestdesignvariablesPanel_5);
            app.UITable10_5.ColumnName = {'Best design'};
            app.UITable10_5.RowName = {};
            app.BestdesignperformancemeasuresPanel_5 = uipanel(app.ParticleSwarmPanel);
            app.BestdesignperformancemeasuresPanel_5.Title = 'Best design performance measures';
            app.UITable11_5 = uitable(app.BestdesignperformancemeasuresPanel_5);
            app.UITable11_5.ColumnName = {'Best measures'};
            app.UITable11_5.RowName = {};
            app.SaveParticleswarmresultsButton = uibutton(app.ParticleSwarmPanel, 'push');
            app.SaveParticleswarmresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParticleswarmresultsButtonPushed, true);
            app.SaveParticleswarmresultsButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveParticleswarmresultsButton.Text = 'Save Particle swarm results';
            app.OptimizeandgettheresultsButton_2 = uibutton(app.SelectionSingleobjectiveoptimizationTab, 'push');
            app.OptimizeandgettheresultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @OptimizeandgettheresultsButton_2Pushed, true);
            app.OptimizeandgettheresultsButton_2.Icon = fullfile(pathToMLAPP, 'solve.png');
            app.OptimizeandgettheresultsButton_2.Text = 'Optimize and get the results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ANAT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end